classdef GUIv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParametricPropulsionModelUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        EditDesignPoint                matlab.ui.container.Menu
        EngineInputsLabel              matlab.ui.control.Label
        GraphicSetupLabel              matlab.ui.control.Label
        RUNANALYSISButton              matlab.ui.control.Button
        Knob                           matlab.ui.control.DiscreteKnob
        maxburnertempunit              matlab.ui.control.EditField
        InletDiameterUnit              matlab.ui.control.EditField
        TOThrustUnit                   matlab.ui.control.EditField
        MaxAltUnit                     matlab.ui.control.EditField
        DPAltUnit                      matlab.ui.control.EditField
        YearDesignedEditFieldLabel     matlab.ui.control.Label
        YearDesignedEditField          matlab.ui.control.NumericEditField
        BypassRatioEditFieldLabel      matlab.ui.control.Label
        BypassRatioEditField           matlab.ui.control.NumericEditField
        BleedRatioEditFieldLabel       matlab.ui.control.Label
        BleedRatioEditField            matlab.ui.control.NumericEditField
        PTOHighWEditFieldLabel         matlab.ui.control.Label
        PTOHighWEditField              matlab.ui.control.NumericEditField
        PTOLowWEditFieldLabel          matlab.ui.control.Label
        PTOLowWEditField               matlab.ui.control.NumericEditField
        InletDiameterEditFieldLabel    matlab.ui.control.Label
        InletDiameterEditField         matlab.ui.control.NumericEditField
        MaximumAltitudeEditFieldLabel  matlab.ui.control.Label
        MaximumAltitudeEditField       matlab.ui.control.NumericEditField
        MaximumMachNumberEditFieldLabel  matlab.ui.control.Label
        MaximumMachNumberEditField     matlab.ui.control.NumericEditField
        AltitudeCurvesDesiredSpinnerLabel  matlab.ui.control.Label
        AltitudeCurvesDesiredSpinner   matlab.ui.control.Spinner
        MaxBurnerTempEditFieldLabel    matlab.ui.control.Label
        MaxBurnerTempEditField         matlab.ui.control.NumericEditField
        TotalCompressorPressureRatioSliderLabel  matlab.ui.control.Label
        TotalCompressorPressureRatioSlider  matlab.ui.control.Slider
        PercentageHPCompressorSliderLabel  matlab.ui.control.Label
        PercentageHPCompressorSlider   matlab.ui.control.Slider
        DesignPointAltitudeEditFieldLabel  matlab.ui.control.Label
        DesignPointAltitudeEditField   matlab.ui.control.NumericEditField
        DesignPointMachEditFieldLabel  matlab.ui.control.Label
        DesignPointMachEditField       matlab.ui.control.NumericEditField
        SeaLevelStaticThrustLabel      matlab.ui.control.Label
        SeaLevelStaticThrustEditField  matlab.ui.control.NumericEditField
        SeaLevelStaticSFCEditFieldLabel  matlab.ui.control.Label
        SeaLevelStaticSFCEditField     matlab.ui.control.NumericEditField
        TabGroup2                      matlab.ui.container.TabGroup
        PlotsTab                       matlab.ui.container.Tab
        UIAxesF                        matlab.ui.control.UIAxes
        UIAxesSFC                      matlab.ui.control.UIAxes
        LineHoldSwitchLabel            matlab.ui.control.Label
        LineHoldSwitch                 matlab.ui.control.Switch
        Table                          matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        AltitudeDropDownLabel          matlab.ui.control.Label
        AltitudeDropDown               matlab.ui.control.DropDown
        FidelitySliderLabel            matlab.ui.control.Label
        FidelitySlider                 matlab.ui.control.Slider
        DataFileNameEditFieldLabel     matlab.ui.control.Label
        DataFileNameEditField          matlab.ui.control.EditField
        DataSavingLabel                matlab.ui.control.Label
        Switch                         matlab.ui.control.ToggleSwitch
        maxburnertempunit_2            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        %initialize variables here to be used globally across multiple functions
        S_scaled;
        F_scaled_N;
        F_scaled_lbf;
        ct = 0;
        alt;
        altft;
        M0;
        run;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNANALYSISButton
        function RUNANALYSISButtonPushed(app, event)
            %% This function runs when the big green RUN ANALYSIS button is pushed. 
            %% Setup
            clc
            app.run = 1;
            addpath([pwd '/AnalysisCode']);
            
            % Make progress bar
            f = uifigure;
            d = uiprogressdlg(f,'Title','Please Wait','Message','Running Engine Performance Analysis','Cancelable','on');
            pause(.1)
            
            % Retreive data from inputs
            M0d = app.DesignPointMachEditField.Value;
            pi_c = app.TotalCompressorPressureRatioSlider.Value;
            ratio = app.PercentageHPCompressorSlider.Value;
            root = nthroot(pi_c,100);
            pi_cH = root^ratio;
            pi_cL = root^(100-ratio);
            
            if strcmp(app.Knob.Value,'English')
                D0 = convlength(app.InletDiameterEditField.Value,'ft','m');
                A0 = pi*(D0/2)^2;
                To_burner = convtemp(app.MaxBurnerTempEditField.Value,'R','K');
                app.alt = app.DesignPointAltitudeEditField.Value;
                T_takeoff = (convforce(app.SeaLevelStaticThrustEditField.Value,'lbf','N'))/1000;
            elseif strcmp(app.Knob.Value,'Metric')
                D0 = app.InletDiameterEditField.Value;
                A0 = pi*(D0/2)^2;
                To_burner = app.MaxBurnerTempEditField.Value;
                app.alt = convlength(app.DesignPointAltitudeEditField.Value,'m','ft');
                T_takeoff = app.SeaLevelStaticThrustEditField.Value;
            end
            
            
            alpha = app.BypassRatioEditField.Value;
            [pi_f] = optimalfan(alpha);
            beta = app.BleedRatioEditField.Value;
            PtoH = app.PTOHighWEditField.Value;
            PtoL = app.PTOLowWEditField.Value;
            year = app.YearDesignedEditField.Value;
            
            SFC_takeoff = app.SeaLevelStaticSFCEditField.Value;
            max_takeoff_speed = 100;
            mdot_low = 0;
            mdot_high = A0*max_takeoff_speed*1.225; %kg/s
            error = 1;
            
            % Calculate on-design parameters
            while norm(error) > .0001
                mdot0 = (mdot_low + mdot_high)/2;
                [state,component,design, inputs, performance] = on_design2(pi_f,pi_cL,pi_cH,alpha,beta,PtoH,PtoL,A0,year,To_burner,mdot0,M0d);
                F = performance{2,1}/1000; %kN
                error = (F-T_takeoff)/T_takeoff;
                if error > 0
                    mdot_high = mdot0;
                else
                    mdot_low = mdot0;
                end
                if norm((mdot_high-mdot_low)/mdot_high) < .00001
                    warning('Corrected mass flow at takeoff did not converge to a value resulting in specified thrust at takeoff. This is most likely due to inconsistent inputs, such as a low burner temperature. Analysis is proceeding assuming with current corrected mass flow')
                    break
                end
            end
            
            
            % Setup altitudes and mach numbers for off-design analysis
            if strcmp(app.Knob.Value,'English')
                An = app.MaximumAltitudeEditField.Value;
            elseif strcmp(app.Knob.Value,'Metric')
                An = convlength(app.MaximumAltitudeEditField.Value,'m','ft');
            end
            Mn = app.MaximumMachNumberEditField.Value;
            n = app.AltitudeCurvesDesiredSpinner.Value;
            m = round((app.FidelitySlider.Value)*.3 + 5);
            
            app.altft = linspace(0,An,n);
            for i = 1:length(app.altft)
                app.alt(i) = convlength(app.altft(i),'ft','m');
            end
            app.M0 = linspace(0,Mn,m); %Operational envelope
            
            S = zeros(size(app.alt));
            F_N = zeros(size(app.alt));
            
            % Calculate off-design parameters
            for i = 1:length(app.alt)
                for j = 1:length(app.M0)
                    
                    [~,~,~,~,performance_i] = off_design2(state,component,design,inputs,app.M0(j),app.alt(i),A0);
                    
                    S(i,j) = performance_i{2,2} / ((.453592/3600)/4.44822);
                    F_N(i,j) = performance_i{2,1}; %[N]
                    d.Value = i/length(app.alt)-.1;
                    d.Message = 'Running Engine Performance Analysis';
                    if d.CancelRequested
                        break
                    end
                    
                end
            end
            
            % Calculate scaled SFC and thrust
            app.S_scaled = SFC_takeoff/S(1,1).*S;
            app.F_scaled_N = T_takeoff/F_N(1,1).*F_N;
            app.F_scaled_lbf = convforce(app.F_scaled_N*1000,'N','lbf');
            
            
            %% Plotting
            d.Message = 'Plotting Graphics';
            d.Value = 1;
            
            % Clearing plots for line hold
            app.LineHoldSwitch.Visible = 'on';
            app.LineHoldSwitchLabel.Visible = 'on';
            if strcmp(app.LineHoldSwitch.Value,'Off')
                cla(app.UIAxesF)
                cla(app.UIAxesSFC)
            elseif strcmp(app.LineHoldSwitch.Value,'On')
                app.ct = app.ct+1;
            end
            
            % Plotting thrust curves
            if app.ct == 0
                for i = 1:length(app.alt)
                    if strcmp(app.Knob.Value,'Metric')
                        plot(app.UIAxesF,app.M0,app.F_scaled_N(i,:),'linewidth',1.5)
                        ylabel(app.UIAxesF,'Thrust (kN)')
                    elseif strcmp(app.Knob.Value,'English')
                        plot(app.UIAxesF,app.M0,app.F_scaled_lbf(i,:),'linewidth',1.5)
                        ylabel(app.UIAxesF,'Thrust (lbf)')
                    end
                end
             % Plotting SFC curves  
                for i = 1:length(app.alt)
                    plot(app.UIAxesSFC,app.M0,app.S_scaled(i,:),'linewidth',1.5);
                end
                title((legend(app.UIAxesF,string(round(app.altft)))),'Altitude (ft)');
                title((legend(app.UIAxesSFC,string(round(app.altft)))),'Altitude (ft)');
                
            % Plotting line hold thrust curves    
            elseif app.ct == 1
                set(app.UIAxesF,'ColorOrderIndex',1)
                set(app.UIAxesSFC,'ColorOrderIndex',1)
                for i = 1:length(app.alt)
                    if strcmp(app.Knob.Value,'Metric')
                        plot(app.UIAxesF,app.M0,app.F_scaled_N(i,:),'--','linewidth',1.5)
                        ylabel(app.UIAxesF,'Thrust (kN)')
                    elseif strcmp(app.Knob.Value,'English')
                        plot(app.UIAxesF,app.M0,app.F_scaled_lbf(i,:),'--','linewidth',1.5)
                        ylabel(app.UIAxesF,'Thrust (lbf)')
                    end
                end
             % Plotting line hold SFC curves   
                for i = 1:length(app.alt)
                    plot(app.UIAxesSFC,app.M0,app.S_scaled(i,:),'--','linewidth',1.5)
                end
                legend(app.UIAxesF,string(round(app.altft)));
                legend(app.UIAxesSFC,string(round(app.altft)))
                
            elseif app.ct == 2
                cla(app.UIAxesF)
                cla(app.UIAxesSFC)
                app.ct = 0;
            end
            
            % Making Tables
            delete(app.UITable)
            app.UITable = uitable(app.Table);
            if strcmp(app.Knob.Value,'English')
                app.UITable.ColumnName = {'Mach Number'; 'Thrust (lbf)'; 'SFC'};
            elseif strcmp(app.Knob.Value,'Metric')
                app.UITable.ColumnName = {'Mach Number'; 'Thrust (kN)'; 'SFC'};
            end
            app.UITable.RowName = {};
            app.UITable.Position = [23 105 631 449];
            app.AltitudeDropDownLabel = uilabel(app.Table);
            app.AltitudeDropDownLabel.HorizontalAlignment = 'right';
            app.AltitudeDropDownLabel.Position = [51 583 46 22];
            app.AltitudeDropDownLabel.Text = 'Altitude';
            app.AltitudeDropDown = uidropdown(app.Table);
            app.AltitudeDropDown.Items = {};
            app.AltitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @AltitudeDropDownValueChanged, true);
            app.AltitudeDropDown.Position = [112 583 100 22];
            app.AltitudeDropDown.Value = {};
            
            app.UITable.Data(:,1) = app.M0(1,:);
            app.UITable.Data(:,2) = app.F_scaled_N(1,:);
            app.UITable.Data(:,3) = app.S_scaled(1,:);
            
            app.AltitudeDropDown.Items = string(round(app.altft));
            
            
            filename = app.DataFileNameEditField.Value; %saving data in folder
            
            if strcmp(app.Switch.Value,'.mat file')
                if strcmp('',filename) == 0
                    data.M = app.M0';
                    data.F = app.F_scaled_lbf';
                    data.SFC = app.S_scaled';
                    data.alt = app.altft';
                    save(filename,'data')
                end
                
            elseif strcmp(app.Switch.Value,'.txt files')
                if strcmp('',filename) == 0
                    [~,~,~] = mkdir(filename);
                    dlmwrite([pwd '/' filename '/Mach.txt'],app.M0')
                    dlmwrite([pwd '/' filename '/Thrust.txt'],app.F_scaled_lbf')
                    dlmwrite([pwd '/' filename '/SFC.txt'],app.S_scaled')
                    dlmwrite([pwd '/' filename '/Alt.txt'],app.altft')
                end
            end
            
            % Closing
            pause(.5)
            close(f);
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            %% This function is run when the metric/english knob is turned
            % It will convert all values between metric and english
            
            clc
            
            if strcmp(app.Knob.Value,'English')
                app.MaximumAltitudeEditField.Value = round(convlength(app.MaximumAltitudeEditField.Value,'m','ft'));
                app.MaxAltUnit.Value = 'ft';
                app.InletDiameterEditField.Value = (convlength(app.InletDiameterEditField.Value,'m','ft'));
                app.InletDiameterUnit.Value = 'ft';
                app.DesignPointAltitudeEditField.Value = round(convlength(app.DesignPointAltitudeEditField.Value,'m','ft'));
                app.DPAltUnit.Value = 'ft';
                app.MaxBurnerTempEditField.Value = (convtemp(app.MaxBurnerTempEditField.Value,'K','R'));
                app.maxburnertempunit.Value = 'R';
                app.SeaLevelStaticThrustEditField.Value = app.SeaLevelStaticThrustEditField.Value*1000;
                app.SeaLevelStaticThrustEditField.Value = (convforce(app.SeaLevelStaticThrustEditField.Value,'N','lbf'));
                app.TOThrustUnit.Value = 'lbf';
                cla(app.UIAxesF)
                delete(app.UITable)
                app.UITable = uitable(app.Table);
                app.UITable.RowName = {};
                app.UITable.Position = [23 149 744 458];
                app.UITable.ColumnName = {'Mach Number'; 'Thrust (lbf)'; 'SFC'};
                if app.run == 1
                app.UITable.Data(:,1) = app.M0(1,:);
                app.UITable.Data(:,2) = app.F_scaled_lbf(1,:);
                app.UITable.Data(:,3) = app.S_scaled(1,:);
                for i = 1:length(app.alt)
                    plot(app.UIAxesF,app.M0,app.F_scaled_lbf(i,:),'linewidth',1.5)
                end
                legend(app.UIAxesF,string(round(app.altft)));
                end
                ylabel(app.UIAxesF, 'Thrust (lbf)')
                app.UITable.ColumnName = {'Mach Number'; 'Thrust (lbf)'; 'SFC'};
            end
            
            if strcmp(app.Knob.Value,'Metric')
                app.MaximumAltitudeEditField.Value = round(convlength(app.MaximumAltitudeEditField.Value,'ft','m'));
                app.MaxAltUnit.Value = 'm';
                app.InletDiameterEditField.Value = (convlength(app.InletDiameterEditField.Value,'ft','m'));
                app.InletDiameterUnit.Value = 'm';
                app.DesignPointAltitudeEditField.Value = round(convlength(app.DesignPointAltitudeEditField.Value,'ft','m'));
                app.DPAltUnit.Value = 'm';
                app.MaxBurnerTempEditField.Value = (convtemp(app.MaxBurnerTempEditField.Value,'R','K'));
                app.maxburnertempunit.Value = 'K';
                app.SeaLevelStaticThrustEditField.Value = app.SeaLevelStaticThrustEditField.Value/1000;
                app.SeaLevelStaticThrustEditField.Value = (convforce(app.SeaLevelStaticThrustEditField.Value,'lbf','N'));
                app.TOThrustUnit.Value = 'kN';
                cla(app.UIAxesF)
                delete(app.UITable)
                app.UITable = uitable(app.Table);
                app.UITable.RowName = {};
                app.UITable.Position = [23 149 744 458];
                app.UITable.ColumnName = {'Mach Number'; 'Thrust (kN)'; 'SFC'};
                if app.run == 1
                app.UITable.Data(:,1) = app.M0(1,:);
                app.UITable.Data(:,2) = app.F_scaled_N(1,:);
                app.UITable.Data(:,3) = app.S_scaled(1,:);
                for i = 1:length(app.alt)
                    plot(app.UIAxesF,app.M0,app.F_scaled_N(i,:),'linewidth',1.5)
                end
                legend(app.UIAxesF,string(round(app.altft)));
                end
                ylabel(app.UIAxesF, 'Thrust (kN)')
                app.UITable.ColumnName = {'Mach Number'; 'Thrust (kN)'; 'SFC'};
            end
        end

        % Menu selected function: EditDesignPoint
        function EditDesignPointMenuSelected(app, event)
            %% This function is activated when the "Edit Design Point" menu is pushed. 
            % This allows the user to change the on-design condition of the
            % analysis
            
            app.DesignPointAltitudeEditFieldLabel.FontColor = [0 0 0];
            app.DesignPointAltitudeEditField.FontColor = [0 0 0];
            app.DesignPointAltitudeEditField.BackgroundColor = [1 1 1];
            app.DesignPointMachEditFieldLabel.FontColor = [0 0 0];
            app.DesignPointMachEditField.FontColor = [0 0 0];
            app.DesignPointMachEditField.BackgroundColor = [1 1 1];
            app.DPAltUnit.BackgroundColor = [1 1 1];
            app.DPAltUnit.FontColor = [0 0 0];
            
            app.DesignPointMachEditField.Editable = 'on';
            app.DesignPointAltitudeEditField.Editable = 'on';
            
            
        end

        % Value changed function: AltitudeDropDown
        function AltitudeDropDownValueChanged(app, event)
          %% This function allows the user to change the altitude displayed on the tabulated data  
          
            value = app.AltitudeDropDown.Value;
            app.AltitudeDropDown.ItemsData = app.altft;         
            if ischar(value)
                value = str2double(value);
            end
            x = find(abs(app.altft-value)<.1);        
            if strcmp(app.Knob.Value,'Metric')
                app.UITable.Data(:,2) = app.F_scaled_N(x,:);
                app.UITable.Data(:,3) = app.S_scaled(x,:);
            elseif strcmp(app.Knob.Value,'English')
                app.UITable.Data(:,2) = app.F_scaled_lbf(x,:);
                app.UITable.Data(:,3) = app.S_scaled(x,:);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParametricPropulsionModelUIFigure = uifigure('Visible', 'off');
            app.ParametricPropulsionModelUIFigure.Name = 'Parametric Propulsion Model';
            app.FileMenu = uimenu(app.ParametricPropulsionModelUIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.ParametricPropulsionModelUIFigure);
            app.EditMenu.Text = 'Edit';
            app.EditDesignPoint = uimenu(app.EditMenu);
            app.EditDesignPoint.MenuSelectedFcn = createCallbackFcn(app, @EditDesignPointMenuSelected, true);
            app.EditDesignPoint.Text = 'Edit Design Point';
            app.EngineInputsLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.EngineInputsLabel.Text = 'Engine Inputs';
            app.GraphicSetupLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.GraphicSetupLabel.Text = 'Graphic Setup';
            app.RUNANALYSISButton = uibutton(app.ParametricPropulsionModelUIFigure, 'push');
            app.RUNANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @RUNANALYSISButtonPushed, true);
            app.RUNANALYSISButton.Text = 'RUN ANALYSIS';
            app.Knob = uiknob(app.ParametricPropulsionModelUIFigure, 'discrete');
            app.Knob.Items = {'Metric', 'English'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Metric';
            app.maxburnertempunit = uieditfield(app.ParametricPropulsionModelUIFigure, 'text');
            app.maxburnertempunit.Editable = 'off';
            app.maxburnertempunit.Value = 'K';
            app.InletDiameterUnit = uieditfield(app.ParametricPropulsionModelUIFigure, 'text');
            app.InletDiameterUnit.Editable = 'off';
            app.InletDiameterUnit.Value = 'm';
            app.TOThrustUnit = uieditfield(app.ParametricPropulsionModelUIFigure, 'text');
            app.TOThrustUnit.Editable = 'off';
            app.TOThrustUnit.Value = 'kN';
            app.MaxAltUnit = uieditfield(app.ParametricPropulsionModelUIFigure, 'text');
            app.MaxAltUnit.Editable = 'off';
            app.MaxAltUnit.Value = 'm';
            app.DPAltUnit = uieditfield(app.ParametricPropulsionModelUIFigure, 'text');
            app.DPAltUnit.Editable = 'off';
            app.DPAltUnit.Value = 'm';
            app.YearDesignedEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.YearDesignedEditFieldLabel.Text = 'Year Designed';
            app.YearDesignedEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.YearDesignedEditField.ValueDisplayFormat = '%.0f';
            app.YearDesignedEditField.Tooltip = {'Year designed influences the level of technology the engine will contain. This value will determine values like maximum burner temperature'; ' polytropic efficiencies of turbomachinery'; ' efficiencies of mechanical machinery'; ' combustion efficiency'; ' and inlet'; ' burner'; ' and nozzle pressure ratios. This value highly drives overall performance​.'};
            app.YearDesignedEditField.Value = 2011;
            app.BypassRatioEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.BypassRatioEditFieldLabel.Text = 'Bypass Ratio';
            app.BypassRatioEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.BypassRatioEditField.Tooltip = {'Bypass ratio is defined by the mass flow of bypass air divided by the mass flow of the core air and is given at sea level static condition. Bypass ratio varies significantly over the operation envelop predictably from sea level static condition. This value is linked to the fan pressure ratio'; ' a parameter that directly drives bypass air velocity thus contributing very largely to overall performance.​'};
            app.BypassRatioEditField.Value = 6.9;
            app.BleedRatioEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.BleedRatioEditFieldLabel.Text = 'Bleed Ratio';
            app.BleedRatioEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.BleedRatioEditField.ValueDisplayFormat = '%.2f';
            app.BleedRatioEditField.Tooltip = {'Bleed ratio is defined by the mass flow redirected from core flow immediately before entering burner  to the aircraft divided by core flow. If value is unknown'; ' assume a value of .01. Future development of software will only require user to input aircraft bleed required.'};
            app.PTOHighWEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.PTOHighWEditFieldLabel.Text = 'PTO High (W)';
            app.PTOHighWEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.PTOHighWEditField.ValueDisplayFormat = '%.0f';
            app.PTOHighWEditField.Tooltip = {'Power takeoff is the amount of power drawn from the turbine-compressor shafts to drive aircraft equipment. Unless specified otherwise'; ' all power takeoff should come from the high-pressure shaft​'};
            app.PTOLowWEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.PTOLowWEditFieldLabel.Text = 'PTO Low (W)';
            app.PTOLowWEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.PTOLowWEditField.ValueDisplayFormat = '%.0f';
            app.PTOLowWEditField.Tooltip = {'Power takeoff is the amount of power drawn from the turbine-compressor shafts to drive aircraft equipment. Unless specified otherwise'; ' all power takeoff should come from the high-pressure shaft​.'};
            app.InletDiameterEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.InletDiameterEditFieldLabel.Text = 'Inlet Diameter';
            app.InletDiameterEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.InletDiameterEditField.Tooltip = {'Approximation of inlet diameter is required to find mass flow through the engine.  This may be a rough estimate and can be found through either pictures or overall measurements of the engine​'};
            app.InletDiameterEditField.Value = 0.469;
            app.MaximumAltitudeEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.MaximumAltitudeEditFieldLabel.Text = 'Maximum Altitude';
            app.MaximumAltitudeEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.MaximumAltitudeEditField.ValueDisplayFormat = '%.0f';
            app.MaximumAltitudeEditField.Tooltip = {'Maximum Altitude the analysis will model a curve for. This value is usually defined by brochure data as a maximum condition for the engine​.'};
            app.MaximumAltitudeEditField.Value = 7620;
            app.MaximumMachNumberEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.MaximumMachNumberEditFieldLabel.Text = 'Maximum Mach Number';
            app.MaximumMachNumberEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.MaximumMachNumberEditField.ValueDisplayFormat = '%.3f';
            app.MaximumMachNumberEditField.Tooltip = {'Maximum Mach Number the analysis will create curves for. This value is usually defined by brochure data as a maximum condition for the engine.'};
            app.MaximumMachNumberEditField.Value = 0.45;
            app.AltitudeCurvesDesiredSpinnerLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.AltitudeCurvesDesiredSpinnerLabel.Text = 'Altitude Curves Desired';
            app.AltitudeCurvesDesiredSpinner = uispinner(app.ParametricPropulsionModelUIFigure);
            app.AltitudeCurvesDesiredSpinner.RoundFractionalValues = 'on';
            app.AltitudeCurvesDesiredSpinner.ValueDisplayFormat = '%.0f';
            app.AltitudeCurvesDesiredSpinner.Value = 3;
            app.MaxBurnerTempEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.MaxBurnerTempEditFieldLabel.Text = 'Max Burner Temp';
            app.MaxBurnerTempEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.MaxBurnerTempEditField.Tooltip = {'If available'; ' input the maximum burner temperature. If not available'; ' input 0. Note that this temperature is related to but is NOT the maximum turbine temperature'};
            app.MaxBurnerTempEditField.Value = 1294;
            app.TotalCompressorPressureRatioSliderLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.TotalCompressorPressureRatioSliderLabel.Text = 'Total Compressor Pressure Ratio';
            app.TotalCompressorPressureRatioSlider = uislider(app.ParametricPropulsionModelUIFigure);
            app.TotalCompressorPressureRatioSlider.Limits = [15 45];
            app.TotalCompressorPressureRatioSlider.MajorTicks = [15 20 25 30 35 40 45];
            app.TotalCompressorPressureRatioSlider.Tooltip = {'Pressure ratio across the low-pressure and high-pressure compressors. Typical values are approximately 20-30 for a low bypass engine (bypass ratio of .2-1)'; ' and 30-40 for a high bypass engine (bypass ratio of 5+)​'};
            app.TotalCompressorPressureRatioSlider.Value = 24;
            app.PercentageHPCompressorSliderLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.PercentageHPCompressorSliderLabel.Text = 'Percentage HP Compressor';
            app.PercentageHPCompressorSlider = uislider(app.ParametricPropulsionModelUIFigure);
            app.PercentageHPCompressorSlider.Limits = [30 90];
            app.PercentageHPCompressorSlider.MajorTicks = [30 40 50 60 70 80 90];
            app.PercentageHPCompressorSlider.Value = 60;
            app.DesignPointAltitudeEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.DesignPointAltitudeEditFieldLabel.Text = 'Design Point Altitude';
            app.DesignPointAltitudeEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.DesignPointAltitudeEditField.ValueDisplayFormat = '%.0f';
            app.DesignPointAltitudeEditField.Editable = 'off';
            app.DesignPointMachEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.DesignPointMachEditFieldLabel.Text = 'Design Point Mach';
            app.DesignPointMachEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.DesignPointMachEditField.Editable = 'off';
            app.SeaLevelStaticThrustLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.SeaLevelStaticThrustLabel.Text = 'Sea Level Static Thrust';
            app.SeaLevelStaticThrustEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.SeaLevelStaticThrustEditField.ValueDisplayFormat = '%.2f';
            app.SeaLevelStaticThrustEditField.Tooltip = {'Baseline conditions for engine when tested at sea level conditions on a static setup. May also be referred to as "Takeoff Thrust"'};
            app.SeaLevelStaticThrustEditField.Value = 2.8418;
            app.SeaLevelStaticSFCEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.SeaLevelStaticSFCEditFieldLabel.Text = 'Sea Level Static SFC';
            app.SeaLevelStaticSFCEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'numeric');
            app.SeaLevelStaticSFCEditField.ValueDisplayFormat = '%.3f';
            app.SeaLevelStaticSFCEditField.Tooltip = {'Baseline conditions for engine when tested at sea level conditions on a static setup. May also be referred to as "Takeoff SFC"​'};
            app.SeaLevelStaticSFCEditField.Value = 0.53;
            app.TabGroup2 = uitabgroup(app.ParametricPropulsionModelUIFigure);
            app.PlotsTab = uitab(app.TabGroup2);
            app.PlotsTab.Title = 'Plots';
            app.UIAxesF = uiaxes(app.PlotsTab);
            title(app.UIAxesF, 'Thrust vs. Mach Number')
            xlabel(app.UIAxesF, 'Mach Number')
            ylabel(app.UIAxesF, 'Thrust (kN)')
            app.UIAxesSFC = uiaxes(app.PlotsTab);
            title(app.UIAxesSFC, 'SFC vs. Mach Number')
            xlabel(app.UIAxesSFC, 'Mach Number')
            ylabel(app.UIAxesSFC, 'SFC (lb/lbf/hr)')
            app.LineHoldSwitchLabel = uilabel(app.PlotsTab);
            app.LineHoldSwitchLabel.Visible = 'off';
            app.LineHoldSwitchLabel.Text = 'Line Hold';
            app.LineHoldSwitch = uiswitch(app.PlotsTab, 'slider');
            app.LineHoldSwitch.Visible = 'off';
            app.LineHoldSwitch.Tooltip = {'Holds the current plots against a new engine for comparison'};
            app.Table = uitab(app.TabGroup2);
            app.Table.Title = 'Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {'Mach Number'; 'Thrust'; 'SFC'};
            app.UITable.RowName = {};
            app.AltitudeDropDownLabel = uilabel(app.Table);
            app.AltitudeDropDownLabel.Text = 'Altitude';
            app.AltitudeDropDown = uidropdown(app.Table);
            app.AltitudeDropDown.Items = {};
            app.AltitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @AltitudeDropDownValueChanged, true);
            app.AltitudeDropDown.Value = {};
            app.FidelitySliderLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.FidelitySliderLabel.Text = 'Fidelity';
            app.FidelitySlider = uislider(app.ParametricPropulsionModelUIFigure);
            app.FidelitySlider.Tooltip = {'A higher fidelity will increase the amount of data points calculated. It will also increase runtimes.'};
            app.DataFileNameEditFieldLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.DataFileNameEditFieldLabel.Text = 'Data File Name';
            app.DataFileNameEditField = uieditfield(app.ParametricPropulsionModelUIFigure, 'text');
            app.DataSavingLabel = uilabel(app.ParametricPropulsionModelUIFigure);
            app.DataSavingLabel.Text = 'Data Saving';
            app.Switch = uiswitch(app.ParametricPropulsionModelUIFigure, 'toggle');
            app.Switch.Items = {'.txt files', '.mat file'};
            app.Switch.Value = '.txt files';
            app.maxburnertempunit_2 = uieditfield(app.ParametricPropulsionModelUIFigure, 'text');
            app.maxburnertempunit_2.Editable = 'off';
            app.maxburnertempunit_2.Value = 'lb/lbf/hr';
            % Show the figure after all components are created
            app.ParametricPropulsionModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParametricPropulsionModelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParametricPropulsionModelUIFigure)
        end
    end
end