classdef GUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OnDesignParametersLabel       matlab.ui.control.Label
        AltitudeftEditFieldLabel      matlab.ui.control.Label
        MachNumberEditField           matlab.ui.control.NumericEditField
        InletDiametermEditField       matlab.ui.control.NumericEditField
        MachNumberEditFieldLabel      matlab.ui.control.Label
        InletDiametermEditFieldLabel  matlab.ui.control.Label
        AltitudeftEditField           matlab.ui.control.NumericEditField
        RUNButton                     matlab.ui.control.Button
        BypassRatioEditFieldLabel     matlab.ui.control.Label
        BypassRatioEditField          matlab.ui.control.NumericEditField
        BleedRatioEditFieldLabel      matlab.ui.control.Label
        BleedRatioEditField           matlab.ui.control.NumericEditField
        PTOHighEditFieldLabel         matlab.ui.control.Label
        PTOHighEditField              matlab.ui.control.NumericEditField
        PTOLowEditFieldLabel          matlab.ui.control.Label
        PTOLowEditField               matlab.ui.control.NumericEditField
        pi_fSliderLabel               matlab.ui.control.Label
        pi_fSlider                    matlab.ui.control.Slider
        pi_cLSliderLabel              matlab.ui.control.Label
        pi_cLSlider                   matlab.ui.control.Slider
        pi_cHSliderLabel              matlab.ui.control.Label
        pi_cHSlider                   matlab.ui.control.Slider
        YearMadeEditFieldLabel        matlab.ui.control.Label
        YearMadeEditField             matlab.ui.control.NumericEditField
        ThrustGaugeLabel              matlab.ui.control.Label
        ThrustGauge                   matlab.ui.control.Gauge
        SFCGaugeLabel                 matlab.ui.control.Label
        SFCGauge                      matlab.ui.control.Gauge
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %Write Code here
            altft = app.AltitudeftEditField.Value;
            alt = altft/3.281;
            M0 = app.MachNumberEditField.Value;
            %pi_f = app.pi_fSlider.Value;
            pi_cL = app.pi_cLSlider.Value;
            pi_cH = app.pi_cHSlider.Value;
            alpha = app.BypassRatioEditField.Value;
            beta = app.BleedRatioEditField.Value;
            PtoH = app.PTOHighEditField.Value;
            PtoL = app.PTOLowEditField.Value;
            D0 = app.InletDiametermEditField.Value;
            A0 = pi*(D0/2)^2;
            year = app.YearMadeEditField.Value;
            [pi_f] = optimalfan(alpha);
            [state,component,design,inputs,performance] = on_design(alt,M0,pi_f,pi_cL,pi_cH,alpha,beta,PtoH,PtoL,A0,year);
            
            F = performance{2,1} * .224809;
            mdot0 = state{2,5};
            F_mdot = F/mdot0 * (1/9.806655);
            S = performance{2,2} / ((.453592/3600)/4.44822);
            
            app.ThrustGauge.Value = F;
            app.SFCGauge.Value = S;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OnDesignParametersLabel = uilabel(app.UIFigure);
            app.OnDesignParametersLabel.Text = 'On-Design Parameters';
            app.AltitudeftEditFieldLabel = uilabel(app.UIFigure);
            app.AltitudeftEditFieldLabel.Text = 'Altitude (ft)';
            app.MachNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.MachNumberEditField.Value = 0.338;
            app.InletDiametermEditField = uieditfield(app.UIFigure, 'numeric');
            app.InletDiametermEditField.Value = 0.469;
            app.MachNumberEditFieldLabel = uilabel(app.UIFigure);
            app.MachNumberEditFieldLabel.Text = 'Mach Number';
            app.InletDiametermEditFieldLabel = uilabel(app.UIFigure);
            app.InletDiametermEditFieldLabel.Text = 'Inlet Diameter (m)';
            app.AltitudeftEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudeftEditField.ValueDisplayFormat = '%.0f';
            app.AltitudeftEditField.Value = 10000;
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.BypassRatioEditFieldLabel = uilabel(app.UIFigure);
            app.BypassRatioEditFieldLabel.Text = 'Bypass Ratio';
            app.BypassRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.BypassRatioEditField.Value = 6.9;
            app.BleedRatioEditFieldLabel = uilabel(app.UIFigure);
            app.BleedRatioEditFieldLabel.Text = 'Bleed Ratio';
            app.BleedRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.PTOHighEditFieldLabel = uilabel(app.UIFigure);
            app.PTOHighEditFieldLabel.Text = 'PTO High';
            app.PTOHighEditField = uieditfield(app.UIFigure, 'numeric');
            app.PTOLowEditFieldLabel = uilabel(app.UIFigure);
            app.PTOLowEditFieldLabel.Text = 'PTO Low';
            app.PTOLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.pi_fSliderLabel = uilabel(app.UIFigure);
            app.pi_fSliderLabel.Text = 'pi_f';
            app.pi_fSlider = uislider(app.UIFigure);
            app.pi_fSlider.Limits = [1 3];
            app.pi_fSlider.Value = 1.3;
            app.pi_cLSliderLabel = uilabel(app.UIFigure);
            app.pi_cLSliderLabel.Text = 'pi_cL';
            app.pi_cLSlider = uislider(app.UIFigure);
            app.pi_cLSlider.Limits = [1 10];
            app.pi_cLSlider.Value = 3;
            app.pi_cHSliderLabel = uilabel(app.UIFigure);
            app.pi_cHSliderLabel.Text = 'pi_cH';
            app.pi_cHSlider = uislider(app.UIFigure);
            app.pi_cHSlider.Limits = [1 10];
            app.pi_cHSlider.Value = 9;
            app.YearMadeEditFieldLabel = uilabel(app.UIFigure);
            app.YearMadeEditFieldLabel.Text = 'Year Made';
            app.YearMadeEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearMadeEditField.Value = 2011;
            app.ThrustGaugeLabel = uilabel(app.UIFigure);
            app.ThrustGaugeLabel.Text = 'Thrust';
            app.ThrustGauge = uigauge(app.UIFigure, 'circular');
            app.ThrustGauge.Limits = [0 1200];
            app.SFCGaugeLabel = uilabel(app.UIFigure);
            app.SFCGaugeLabel.Text = 'SFC';
            app.SFCGauge = uigauge(app.UIFigure, 'circular');
            app.SFCGauge.Limits = [0.2 1.2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end